classdef labdeviceGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MeasurementPanel               matlab.ui.container.Panel
        Axes                           matlab.ui.control.UIAxes
        Table                          matlab.ui.control.Table
        StatusPanel                    matlab.ui.container.Panel
        StatusLabel                    matlab.ui.control.Label
        NoPanel                        matlab.ui.container.Panel
        CheckBox                       matlab.ui.control.CheckBox
        CheckBox_2                     matlab.ui.control.CheckBox
        CheckBox_3                     matlab.ui.control.CheckBox
        CheckBox_4                     matlab.ui.control.CheckBox
        CheckBox_5                     matlab.ui.control.CheckBox
        CheckBox_6                     matlab.ui.control.CheckBox
        CheckBox_7                     matlab.ui.control.CheckBox
        CheckBox_8                     matlab.ui.control.CheckBox
        CheckBox_9                     matlab.ui.control.CheckBox
        CheckBox_10                    matlab.ui.control.CheckBox
        TABSLabel                      matlab.ui.control.Label
        SettingsPanel                  matlab.ui.container.Panel
        NormenPanel                    matlab.ui.container.Panel
        NormenButtonGroup              matlab.ui.container.ButtonGroup
        CISPRButton                    matlab.ui.control.RadioButton
        IECButton                      matlab.ui.control.RadioButton
        IMFButton                      matlab.ui.control.RadioButton
        RESETButton                    matlab.ui.control.Button
        SAVEButton                     matlab.ui.control.Button
        SelectMeasurementPanel         matlab.ui.container.TabGroup
        RadiatedEmissonTab             matlab.ui.container.Tab
        RE_InputParameterPanel         matlab.ui.container.Panel
        ProjectEditFieldLabel          matlab.ui.control.Label
        ProjectEditField               matlab.ui.control.EditField
        AntennapolDropDownLabel        matlab.ui.control.Label
        AntennapolDropDown             matlab.ui.control.DropDown
        StartfrequencyEditFieldLabel   matlab.ui.control.Label
        StartfrequencyEditField        matlab.ui.control.EditField
        StopfrequencyEditFieldLabel    matlab.ui.control.Label
        StopfrequencyEditField         matlab.ui.control.EditField
        DetectorDropDownLabel          matlab.ui.control.Label
        DetectorDropDown               matlab.ui.control.DropDown
        MeasurementTimeEditFieldLabel  matlab.ui.control.Label
        MeasurementTimeEditField       matlab.ui.control.EditField
        CommentEditFieldLabel          matlab.ui.control.Label
        CommentEditField               matlab.ui.control.EditField
        RE_EstablishConnectionPanel    matlab.ui.container.Panel
        ESRPConnectionSelectGroup      matlab.ui.container.ButtonGroup
        GPIBButton                     matlab.ui.control.RadioButton
        EthernetButton                 matlab.ui.control.RadioButton
        TestConnectionButton           matlab.ui.control.Button
        IndexLabel                     matlab.ui.control.Label
        ConductedEmissionTab           matlab.ui.container.Tab
        SchirmdmpfungTab               matlab.ui.container.Tab
        OtherMeasurementTab            matlab.ui.container.Tab
        ButtenAndStatusPanel           matlab.ui.container.Panel
        StartMeasurementButton         matlab.ui.control.Button
        StatusLampPanel                matlab.ui.container.Panel
        MeasLampLabel                  matlab.ui.control.Label
        MeasLamp                       matlab.ui.control.Lamp
        WaitLampLabel                  matlab.ui.control.Label
        WaitLamp                       matlab.ui.control.Lamp
        ReadyLampLabel                 matlab.ui.control.Label
        ReadyLamp                      matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        InstrObj
        Index
        MeasRow
        LastRow
        MeasMat
    end
    
    
    methods (Access = private)
        
        function index(app)
            if app.Index < 10
                app.Index = app.Index + 1;
                app.IndexLabel.Text = sprintf("Index: %d", app.Index);
            else
                app.StatusLabel.Text = "Maximale Anzahl an Messungen erreicht. Drücke SAVE um Messungen zu speichern oder RESET um zu löschen";
            end
        end
    % That does not work, because every input in the Edit Fields will be
    % saved as a character vector ('test'). Numeric Numbers and strings.
%         function n = parameterControl(app)
%             n = 0;
%             if ~ischar(app.ProjectEditField.Value)
%                 n = n + 1;
%             end
%             if ~ischar(app.CommentEditField.Value)
%                 n = n + 1;
%             end
%             if ~isnumeric(app.StartfrequencyEditField.Value)
%                 n = n + 1;
%             end
%             if ~isnumeric(app.StopfrequencyEditField.Value)
%                 n = n + 1;
%             end
%             if ~isnumeric(app.MeasurementTimeEditField.Value)
%                 n = n + 1;
%             end
%         end
    
        function n = rowCreator(app)
            
            app.MeasRow = [app.Index,                ...
                cellstr(app.ProjectEditField.Value), ...
                app.CommentEditField.Value,          ...
                app.StartfrequencyEditField.Value,   ...
                app.StopfrequencyEditField.Value,    ...
                app.MeasurementTimeEditField.Value,  ...
                app.DetectorDropDown.Value,          ...
                app.AntennapolDropDown.Value,        ...
                date];
            n = length(app.MeasRow);
            
            
        end
        
        function matCreator(app)
            if app.Index > 1
                app.MeasMat = [app.MeasRow; app.MeasMat];
            else
                app.MeasMat = app.MeasRow;
            end
        end
        
        function tableEdit(app)
            tData = array2table(app.MeasMat);
            app.Table.Data = tData;
        end
        
        function resetTable(app)
            app.MeasMat = ["", "", "", "", "", "", "", "", ""];
            app.tableEdit;
        end
        
        function tableSupervision(app)
            index(app);
            number = rowCreator(app);
                    
            if number == 9
                app.matCreator;
                app.tableEdit;
                    
%               app.InstrObj = radiatedEmissionGUI(app.InstrObj,                      ...
%                                   app.DetectorDropDown.Value,        ...
%                                   app.StartfrequencyEditField.Value, ...
%                                   app.StopfrequencyEditField.Value,  ...
%                                   app.MeasurementTimeEditField.Value);
            else
                app.StatusLabel.Text = "Eingabeparameter nicht vollständig! Bitte ergänzen und Messung neu starten.";
                app.Index = app.Index - 1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            RESETButtonPushed(app);
        end

        % Value changed function: ProjectEditField
        function ProjectEditFieldValueChanged(app, event)
            value = app.ProjectEditField.Value;
            app.Axes.Title.String = value;
        end

        % Button pushed function: StartMeasurementButton
        function StartMeasurementButtonPushed(app, event)
           app.StatusLabel.Text = "";
           tableSupervision(app);
        end

        % Button pushed function: TestConnectionButton
        function TestConnectionButtonPushed(app, event)
            app.InstrObj = InitializeESRPConnectionGUI(app.GPIBButton.Value);
        end

        % Selection changed function: ESRPConnectionSelectGroup
        function ESRPConnectionSelectGroupSelectionChanged(app, event)
            %selectedButton = app.ESRPConnectionSelectGroup.SelectedObject;
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.ProjectEditField.Value = "";
            app.AntennapolDropDown.Value = "horizontal";
            app.StartfrequencyEditField.Value = "";
            app.StopfrequencyEditField.Value = "";
            app.DetectorDropDown.Value = "qpeak";
            app.MeasurementTimeEditField.Value = "";
            app.CommentEditField.Value = "";
            
            if app.Index
                app.StatusLabel.Text = "RESET. Neue Messungen können jetzt durchgeführt werden.";
            end
            app.Index = 0;
            app.IndexLabel.Text = sprintf("Index: ");
            
            app.resetTable;
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            %saveas(app.Axes, 'test', 'fig');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MeasurementPanel = uipanel(app.UIFigure);
            app.Axes = uiaxes(app.MeasurementPanel);
            title(app.Axes, '')
            xlabel(app.Axes, 'f/MHz')
            ylabel(app.Axes, 'U/(dBuV/m)')
            app.Axes.XTick = [0.1 1];
            app.Axes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Axes.XScale = 'log';
            app.Table = uitable(app.MeasurementPanel);
            app.Table.ColumnName = {'No.'; 'Project'; 'Comment'; 'By'; 'To'; 'Measurement Time'; 'Detector'; 'AntennaPol'; 'Date'};
            app.Table.RowName = {''};
            app.Table.ColumnEditable = false;
            app.StatusPanel = uipanel(app.MeasurementPanel);
            app.StatusLabel = uilabel(app.StatusPanel);
            app.StatusLabel.Tooltip = {'Jetzt füll doch einfach alle Zeilen aus!'; 'So schwer kann das doch nicht sein.'};
            app.StatusLabel.Text = '';
            app.NoPanel = uipanel(app.MeasurementPanel);
            app.NoPanel.Title = 'No';
            app.CheckBox = uicheckbox(app.NoPanel);
            app.CheckBox.Text = '1';
            app.CheckBox_2 = uicheckbox(app.NoPanel);
            app.CheckBox_2.Text = '2';
            app.CheckBox_3 = uicheckbox(app.NoPanel);
            app.CheckBox_3.Text = '3';
            app.CheckBox_4 = uicheckbox(app.NoPanel);
            app.CheckBox_4.Text = '4';
            app.CheckBox_5 = uicheckbox(app.NoPanel);
            app.CheckBox_5.Text = '5';
            app.CheckBox_6 = uicheckbox(app.NoPanel);
            app.CheckBox_6.Text = '6';
            app.CheckBox_7 = uicheckbox(app.NoPanel);
            app.CheckBox_7.Text = '7';
            app.CheckBox_8 = uicheckbox(app.NoPanel);
            app.CheckBox_8.Text = '8';
            app.CheckBox_9 = uicheckbox(app.NoPanel);
            app.CheckBox_9.Text = '9';
            app.CheckBox_10 = uicheckbox(app.NoPanel);
            app.CheckBox_10.Text = '10';
            app.TABSLabel = uilabel(app.MeasurementPanel);
            app.TABSLabel.Text = 'TABS!';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.NormenPanel = uipanel(app.SettingsPanel);
            app.NormenButtonGroup = uibuttongroup(app.NormenPanel);
            app.NormenButtonGroup.Title = 'Normen';
            app.CISPRButton = uiradiobutton(app.NormenButtonGroup);
            app.CISPRButton.Text = 'CISPR';
            app.CISPRButton.Value = true;
            app.IECButton = uiradiobutton(app.NormenButtonGroup);
            app.IECButton.Text = 'IEC';
            app.IMFButton = uiradiobutton(app.NormenButtonGroup);
            app.IMFButton.Text = 'IMF';
            app.RESETButton = uibutton(app.NormenPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.SAVEButton = uibutton(app.NormenPanel, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.SelectMeasurementPanel = uitabgroup(app.SettingsPanel);
            app.RadiatedEmissonTab = uitab(app.SelectMeasurementPanel);
            app.RadiatedEmissonTab.Title = 'Radiated Emisson';
            app.RE_InputParameterPanel = uipanel(app.RadiatedEmissonTab);
            app.ProjectEditFieldLabel = uilabel(app.RE_InputParameterPanel);
            app.ProjectEditFieldLabel.Text = 'Project';
            app.ProjectEditField = uieditfield(app.RE_InputParameterPanel, 'text');
            app.ProjectEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectEditFieldValueChanged, true);
            app.AntennapolDropDownLabel = uilabel(app.RE_InputParameterPanel);
            app.AntennapolDropDownLabel.Text = 'Antenna pol.';
            app.AntennapolDropDown = uidropdown(app.RE_InputParameterPanel);
            app.AntennapolDropDown.Items = {'horizontal', 'vertical'};
            app.AntennapolDropDown.Value = 'horizontal';
            app.StartfrequencyEditFieldLabel = uilabel(app.RE_InputParameterPanel);
            app.StartfrequencyEditFieldLabel.Text = 'Start frequency';
            app.StartfrequencyEditField = uieditfield(app.RE_InputParameterPanel, 'text');
            app.StopfrequencyEditFieldLabel = uilabel(app.RE_InputParameterPanel);
            app.StopfrequencyEditFieldLabel.Text = 'Stop frequency';
            app.StopfrequencyEditField = uieditfield(app.RE_InputParameterPanel, 'text');
            app.DetectorDropDownLabel = uilabel(app.RE_InputParameterPanel);
            app.DetectorDropDownLabel.Text = 'Detector';
            app.DetectorDropDown = uidropdown(app.RE_InputParameterPanel);
            app.DetectorDropDown.Items = {'qpeak', 'mpeak', 'aver'};
            app.DetectorDropDown.Value = 'qpeak';
            app.MeasurementTimeEditFieldLabel = uilabel(app.RE_InputParameterPanel);
            app.MeasurementTimeEditFieldLabel.Text = 'Measurement Time';
            app.MeasurementTimeEditField = uieditfield(app.RE_InputParameterPanel, 'text');
            app.CommentEditFieldLabel = uilabel(app.RE_InputParameterPanel);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.CommentEditField = uieditfield(app.RE_InputParameterPanel, 'text');
            app.RE_EstablishConnectionPanel = uipanel(app.RadiatedEmissonTab);
            app.ESRPConnectionSelectGroup = uibuttongroup(app.RE_EstablishConnectionPanel);
            app.ESRPConnectionSelectGroup.SelectionChangedFcn = createCallbackFcn(app, @ESRPConnectionSelectGroupSelectionChanged, true);
            app.ESRPConnectionSelectGroup.Title = 'R&S ESRP';
            app.GPIBButton = uiradiobutton(app.ESRPConnectionSelectGroup);
            app.GPIBButton.Text = 'GPIB';
            app.GPIBButton.Value = true;
            app.EthernetButton = uiradiobutton(app.ESRPConnectionSelectGroup);
            app.EthernetButton.Text = 'Ethernet';
            app.TestConnectionButton = uibutton(app.RE_EstablishConnectionPanel, 'push');
            app.TestConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @TestConnectionButtonPushed, true);
            app.TestConnectionButton.Text = 'Test Connection';
            app.IndexLabel = uilabel(app.RE_EstablishConnectionPanel);
            app.IndexLabel.Text = 'Index:';
            app.ConductedEmissionTab = uitab(app.SelectMeasurementPanel);
            app.ConductedEmissionTab.Title = 'Conducted Emission';
            app.SchirmdmpfungTab = uitab(app.SelectMeasurementPanel);
            app.SchirmdmpfungTab.Title = 'Schirmdämpfung';
            app.OtherMeasurementTab = uitab(app.SelectMeasurementPanel);
            app.OtherMeasurementTab.Title = 'Other Measurement';
            app.ButtenAndStatusPanel = uipanel(app.SettingsPanel);
            app.StartMeasurementButton = uibutton(app.ButtenAndStatusPanel, 'push');
            app.StartMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StartMeasurementButtonPushed, true);
            app.StartMeasurementButton.Text = {'Start'; 'Measurement'};
            app.StatusLampPanel = uipanel(app.ButtenAndStatusPanel);
            app.MeasLampLabel = uilabel(app.StatusLampPanel);
            app.MeasLampLabel.Text = 'Meas';
            app.MeasLamp = uilamp(app.StatusLampPanel);
            app.WaitLampLabel = uilabel(app.StatusLampPanel);
            app.WaitLampLabel.Text = 'Wait';
            app.WaitLamp = uilamp(app.StatusLampPanel);
            app.ReadyLampLabel = uilabel(app.StatusLampPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.StatusLampPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = labdeviceGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end